classdef GUI_Proto_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CapstoneGroupHUIFigure  matlab.ui.Figure
        EventsListBoxLabel      matlab.ui.control.Label
        EventsListBox           matlab.ui.control.ListBox
        TabGroup                matlab.ui.container.TabGroup
        SignalsTab              matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        SignalsPanel            matlab.ui.container.Panel
        Signal1CheckBox         matlab.ui.control.CheckBox
        Signal2CheckBox         matlab.ui.control.CheckBox
        Signal3CheckBox         matlab.ui.control.CheckBox
        FFTTab                  matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
        SignalsPanel_2          matlab.ui.container.Panel
        Signal1CheckBox_2       matlab.ui.control.CheckBox
        Signal2CheckBox_2       matlab.ui.control.CheckBox
        Signal3CheckBox_2       matlab.ui.control.CheckBox
        CalculationsTab         matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        SettingsTab             matlab.ui.container.Tab
        MinSliderLabel          matlab.ui.control.Label
        MinSlider               matlab.ui.control.Slider
        MaxSliderLabel          matlab.ui.control.Label
        MaxSlider               matlab.ui.control.Slider
        GroupMembersTextArea    matlab.ui.control.TextArea
        TextArea                matlab.ui.control.TextArea
        ZoomLabel               matlab.ui.control.Label
        AboutLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        p1;
        p2;
        p3;
        p4;
        p5;
        p6;
        info;
        event;
        data1;
        data2;
        data3;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            set(app.TabGroup,'visible','off');
            
            % Top channel- venous pressure recording in an ear vein
            % Middle channel- venous pressure recording in one of the limbs
            % Bottom channel- arterial pressure recording with a piezo electric pressure sensor near the neck of the animal
            % Select which LabChart (.adicht) file to use
            f = adi.readFile;
            
            % Store channels
            ch1 = f.getChannelByName('Channel 1');
            ch2 = f.getChannelByName('Channel 2');
            ch3 = f.getChannelByName('Channel 3');
                                   
            % Store the data
            app.data1 = ch1.getData(1);
            app.data2 = ch2.getData(1);
            app.data3 = ch3.getData(1);
            app.event = eventDetector.detector('Events');
                                    
            app.p1 = plot(app.UIAxes,FilterData.filter(app.data1(1:30001), 8, .1, 20, 4, .1, .001));
            app.p2 = plot(app.UIAxes,FilterData.filter(app.data2(1:30001), 8, .1, 20, 4, .1, .001));
            app.p3 = plot(app.UIAxes,FilterData.filter(app.data3(1:30001), 8, .04, 20, 8, .2, 1));
            
            % eventName = zeros(length(info),1);
            for i = 1:length(app.event.eventName)
                eventName(i) = app.event.eventName(i);
                itemsData(i) = i;
            end
            
            app.EventsListBox.Items = eventName;
            app.EventsListBox.ItemsData = itemsData();
            app.EventsListBox.Value = 1;
            
            set(app.TabGroup,'visible','on');
        end

        % Callback function: EventsListBox, MaxSlider, MinSlider, 
        % Signal1CheckBox, Signal1CheckBox_2, Signal2CheckBox, 
        % Signal2CheckBox_2, Signal3CheckBox, Signal3CheckBox_2, TabGroup
        function ValueChanged(app, event)
            value = get(app.MinSlider, 'value');
            limits = [value, 30000];
            set(app.MaxSlider,'Limits', limits);
            
            min = get(app.MinSlider, 'value');
            max = get(app.MaxSlider, 'value');
            
            eventId = get(app.EventsListBox, 'Value');
            
            tab = get(app.TabGroup, 'SelectedTab');
            % disp(tab.Title);
            
            index = app.event.eventIndex(eventId);
            
            if strcmp(tab.Title,'Signals')
                cla(app.UIAxes);
                filt1 = FilterData.filter(app.data1(index:index+30000), 8, .1, 20, 4, .1, .001);
                filt2 = FilterData.filter(app.data2(index:index+30000), 8, .1, 20, 4, .1, .001);
                filt3 = FilterData.filter(app.data3(index:index+30000), 8, .04, 20, 8, .2, 1);
                app.p1 = plot(app.UIAxes,filt1(min:max));
                app.p2 = plot(app.UIAxes,filt2(min:max));
                app.p3 = plot(app.UIAxes,filt3(min:max));
            %                app.p1 = plot(app.UIAxes,app.info(eventId).filter1(min:max));
            %                app.p2 = plot(app.UIAxes,app.info(eventId).filter2(min:max));
            %                app.p3 = plot(app.UIAxes,app.info(eventId).filter3(min:max));
            
                value = app.Signal1CheckBox.Value;
                if value == 0
                    app.p1.set('visible','off');
                else
                    app.p1.set('visible','on');
                end
            
                value = app.Signal2CheckBox.Value;
                if value == 0
                    app.p2.set('visible','off');
                else
                    app.p2.set('visible','on');
                end
            
                value = app.Signal3CheckBox.Value;
                if value == 0
                    app.p3.set('visible','off');
                else
                    app.p3.set('visible','on');
                end
            
            elseif strcmp(tab.Title,'FFT')
                cla(app.UIAxes2);
                f1 = Fourier.FFourier2(app.data1(index:index+30000));
                f2 = Fourier.FFourier2(app.data2(index:index+30000));
                f3 = Fourier.FFourier2(app.data3(index:index+30000));
                app.p4 = plot(app.UIAxes2,f1(min/2:max/2));
                app.p5 = plot(app.UIAxes2,f2(min/2:max/2));
                app.p6 = plot(app.UIAxes2,f3(min/2:max/2));
            %                app.p4 = plot(app.UIAxes2,app.info(eventId).fourier1(min/2:max/2));
            %                app.p5 = plot(app.UIAxes2,app.info(eventId).fourier2(min/2:max/2));
            %                app.p6 = plot(app.UIAxes2,app.info(eventId).fourier3(min/2:max/2));
            
                value = app.Signal1CheckBox_2.Value;
                if value == 0
                    app.p4.set('visible','off');
                else
                    app.p4.set('visible','on');
                end
            
                value = app.Signal2CheckBox_2.Value;
                if value == 0
                    app.p5.set('visible','off');
                else
                    app.p5.set('visible','on');
                end
            
                value = app.Signal3CheckBox_2.Value;
                if value == 0
                    app.p6.set('visible','off');
                else
                    app.p6.set('visible','on');
                end
            
            elseif strcmp(tab.Title,'Calculations')
                calc = CalcUnit.study(app.data1(index:index+30000), app.data2(index:index+30000), app.data3(index:index+30000));
                app.UITable.Data = {'Signal1',calc.maxi(1),calc.mini(1),calc.avrg(1),calc.medi(1),calc.stdDev(1),calc.vari(1);'Signal2',calc.maxi(2),calc.mini(2),calc.avrg(2),calc.medi(2),calc.stdDev(2),calc.vari(2);'Signal3',calc.maxi(3),calc.mini(3),calc.avrg(3),calc.medi(3),calc.stdDev(3),calc.vari(3)};
            %                app.UITable.Data = {'Signal1',app.info(eventId).max(1),app.info(eventId).min(1),app.info(eventId).avg(1),app.info(eventId).med(1),app.info(eventId).stdDev(1),app.info(eventId).var(1);'Signal2',app.info(eventId).max(2),app.info(eventId).min(2),app.info(eventId).avg(2),app.info(eventId).med(2),app.info(eventId).stdDev(2),app.info(eventId).var(2);'Signal3',app.info(eventId).max(3),app.info(eventId).min(3),app.info(eventId).avg(3),app.info(eventId).med(3),app.info(eventId).stdDev(3),app.info(eventId).var(3)};
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CapstoneGroupHUIFigure = uifigure;
            app.CapstoneGroupHUIFigure.Name = 'Capstone Group H';
            setAutoResize(app, app.CapstoneGroupHUIFigure, true)
            app.EventsListBoxLabel = uilabel(app.CapstoneGroupHUIFigure);
            app.EventsListBoxLabel.Text = 'Events';
            app.EventsListBox = uilistbox(app.CapstoneGroupHUIFigure);
            app.EventsListBox.Items = {};
            app.EventsListBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EventsListBox.Value = {};
            app.TabGroup = uitabgroup(app.CapstoneGroupHUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.SignalsTab = uitab(app.TabGroup);
            app.SignalsTab.Title = 'Signals';
            app.SignalsTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.SignalsTab);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.SignalsPanel = uipanel(app.SignalsTab);
            app.SignalsPanel.Title = 'Signals';
            app.Signal1CheckBox = uicheckbox(app.SignalsPanel);
            app.Signal1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal1CheckBox.Text = 'Signal 1';
            app.Signal1CheckBox.Value = true;
            app.Signal2CheckBox = uicheckbox(app.SignalsPanel);
            app.Signal2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal2CheckBox.Text = 'Signal 2';
            app.Signal2CheckBox.Value = true;
            app.Signal3CheckBox = uicheckbox(app.SignalsPanel);
            app.Signal3CheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal3CheckBox.Text = 'Signal 3';
            app.Signal3CheckBox.Value = true;
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.FFTTab.Units = 'pixels';
            app.UIAxes2 = uiaxes(app.FFTTab);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.SignalsPanel_2 = uipanel(app.FFTTab);
            app.SignalsPanel_2.Title = 'Signals';
            app.Signal1CheckBox_2 = uicheckbox(app.SignalsPanel_2);
            app.Signal1CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal1CheckBox_2.Text = 'Signal 1';
            app.Signal1CheckBox_2.Value = true;
            app.Signal2CheckBox_2 = uicheckbox(app.SignalsPanel_2);
            app.Signal2CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal2CheckBox_2.Text = 'Signal 2';
            app.Signal2CheckBox_2.Value = true;
            app.Signal3CheckBox_2 = uicheckbox(app.SignalsPanel_2);
            app.Signal3CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal3CheckBox_2.Text = 'Signal 3';
            app.Signal3CheckBox_2.Value = true;
            app.CalculationsTab = uitab(app.TabGroup);
            app.CalculationsTab.Title = 'Calculations';
            app.CalculationsTab.Units = 'pixels';
            app.UITable = uitable(app.CalculationsTab);
            app.UITable.ColumnName = {'Name'; 'Max'; 'Min'; 'Average'; 'Median'; 'Std Dev'; 'Variance'};
            app.UITable.RowName = {};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsTab.Units = 'pixels';
            app.MinSliderLabel = uilabel(app.SettingsTab);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.SettingsTab);
            app.MinSlider.Limits = [1 30000];
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.MinSlider.MinorTicks = [];
            app.MinSlider.Value = 1;
            app.MaxSliderLabel = uilabel(app.SettingsTab);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.SettingsTab);
            app.MaxSlider.Limits = [1 30000];
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.MaxSlider.MinorTicks = [];
            app.MaxSlider.Value = 30000;
            app.GroupMembersTextArea = uitextarea(app.SettingsTab);
            app.GroupMembersTextArea.Editable = 'off';
            app.GroupMembersTextArea.Value = {'Group Members:'; 'John Alumbaugh, Chris Brown, Joseph Jenkins, Donovan Montgomery, and Jae Ryu'};
            app.TextArea = uitextarea(app.SettingsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Events: '; 'The events box lists all events associated with the data. Clicking on an event will display the data associated with that event.'; ''; 'Signals: '; 'The signals tab show the graph of the three signals associated with an event. The graph shows 30 seconds worth of data beginning immediately after an event happens. The three check boxes below the graph allow you to select which signals you want to be shown on the graph.'; ''; 'Fast Fourier Transform: '; 'The FFT tab show the graph of the Fast Fourier Transform of the three signals associated with an event. The graph shows 30 seconds worth of data beginning immediately after an event happens. The three check boxes below the graph allow you to select which signals you want to be shown on the graph.'; ''; 'Calculations: '; 'The calculations tab displays, in a table, the max, min, average, median, standard deviation, and variance of all three signals associated with an event.'; ''; 'Settings: '; 'The settings tab contains settings for adjusting the graphs and information about this software.  The two slider bars can be used to zoom in on the graphs by setting the min and max index to be shown on the graphs.'};
            app.ZoomLabel = uilabel(app.SettingsTab);
            app.ZoomLabel.Text = 'Zoom:';
            app.AboutLabel = uilabel(app.SettingsTab);
            app.AboutLabel.Text = 'About:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Proto_v2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CapstoneGroupHUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CapstoneGroupHUIFigure)
        end
    end
end